classdef synthesizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        C3                              matlab.ui.control.Button
        C3_Sharp                        matlab.ui.control.Button
        D3                              matlab.ui.control.Button
        D3_Sharp                        matlab.ui.control.Button
        E3Button                        matlab.ui.control.Button
        F3                              matlab.ui.control.Button
        F3_Sharp                        matlab.ui.control.Button
        G3                              matlab.ui.control.Button
        G3_Sharp                        matlab.ui.control.Button
        A3                              matlab.ui.control.Button
        A3_Sharp                        matlab.ui.control.Button
        B3                              matlab.ui.control.Button
        WaveTypeListBoxLabel            matlab.ui.control.Label
        WaveTypeListBox                 matlab.ui.control.ListBox
        RecordSwitchLabel               matlab.ui.control.Label
        RecordSwitch                    matlab.ui.control.Switch
        PlayRecordingSwitchLabel        matlab.ui.control.Label
        PlayRecordingSwitch             matlab.ui.control.ToggleSwitch
        WaveDisplay                     matlab.ui.control.UIAxes
        C4                              matlab.ui.control.Button
        C4_Sharp                        matlab.ui.control.Button
        D4                              matlab.ui.control.Button
        D4_Sharp                        matlab.ui.control.Button
        E4                              matlab.ui.control.Button
        F4                              matlab.ui.control.Button
        F4_Sharp                        matlab.ui.control.Button
        G4                              matlab.ui.control.Button
        G4_Sharp                        matlab.ui.control.Button
        A4                              matlab.ui.control.Button
        A4_Sharp                        matlab.ui.control.Button
        B4                              matlab.ui.control.Button
        DistortionSliderLabel           matlab.ui.control.Label
        DistortionSlider                matlab.ui.control.Slider
        ExportRecordingButton           matlab.ui.control.Button
        RecordingLengthInSecondsSliderLabel  matlab.ui.control.Label
        RecordingLengthInSecondsSlider  matlab.ui.control.Slider
        ResetRecordingButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        distortion % variable for distortion
        sinwave % boolean for if the sine wave is being used
        sawwave % boolean for if the saw wave is being used
        squarewave % boolean for if the square wave is being used
        time = 1.5 % This is a static value for how long a single key's sound will be played
        fs = 15000 % Static value for a sample rate. It is set high for high sound quality.
        graphwave % variable where all the current waves being played are going to be compiled
        recordwave % variable where all the recorded waves are being stored
    end
    
    methods (Access = private)
        
        
        function results = play_sound(app, freq, dis, sinebool, sawbool, sqrbool)
            %freq stands for frequency of the sound. dis stands for the
            %distortion property of the sound, sin saw, sqr(for square) respectively
            %are the booleans for whether the wave is going to be sin, saw,
            %or a square wave.
            
            recording_check(app) %checks for if the recording has gone past the given time

            
            recordpad = length(app.recordwave); %length of matrix we record values in
            
            time_conv = 1/freq; %this finds how many seconds is occupied 1 period for the frequency
            time_conv = app.time/time_conv; %then we find how many periods are needed for 1.2 seconds for the frequency
            time_conv = time_conv*(1/freq); %then we convert it so we can use it for the wave
            
            wave = 0:1/app.fs:time_conv-1/app.fs; %these are x values for the wave
            
            if sqrbool == 1
                wave = square(2*pi*freq*wave); %square wave
            elseif sawbool == 1 
                wave = sawtooth(2*pi*freq*wave); %saw wave
            else 
                wave = sin(2*pi*freq*wave); %sinwave
                %default is sinwave
            end
            
            poswave = wave > 1-dis; %positive values of the wave
            negwave = wave < dis-1; %negative values of the wave
            
            wave(poswave) = 1-dis; %positive parts of wave getting distorted
            wave(negwave) = dis-1; %negative parts of wave getting distorted
            
            %records the sound waves
            if strcmp(app.RecordSwitch.Value, 'On')
                hi = toc;
                initial = round(toc, 4); %first measures the time elapsed and rounds it to 4 decimals
                initial = initial*(10^4); %to convert to the indices of recordwave
                initial = [zeros(1,round(initial)-1),wave];%pads with zeros to make sure matrix dimensions match with app.recordwave
                init_len = length(initial); %length of array
                initial = [initial, zeros(1, recordpad - init_len)]; %pad the end with zeros if needed to make sure matrix dimensions match with app.recordwave
                initial = initial(:, 1:recordpad); %cut off any sound that shouldn't be recorded because it goes past the recording time
                app.recordwave = initial + app.recordwave;
            end
            
            
            sound(wave, app.fs) %playing sound
            
            plot_wave(app, wave) %plots soundwaves of the most recent note

            
        end
        
        
        function results = plot_wave(app, wave)
            %wave is the soundwave value in play_sound.
            %plots soundwaves of the most recent note
            plot(app.WaveDisplay,wave)
            ylim(app.WaveDisplay, [-2 2])
            xlim(app.WaveDisplay, [0 2000])
        end
        
        
        function results = recording_check(app)
            %checks if the recording has gone past time
            if strcmp(app.RecordSwitch.Value, 'On')
                if toc > app.RecordingLengthInSecondsSlider.Value
                    app.RecordSwitch.Value = 'Off'; %if recording has gone past, turn off
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %this is code for setting default values and initial conditions
            %the program will run on for when I start it up
            app.distortion = 0; %default distortion value is 0
            app.sinwave = 1; %sin wave is default on
            app.sawwave = 0; %saw wave is default off
            app.squarewave = 0; %square wave is default off
            app.RecordSwitch.Value = 'Off'; %recording switch is default off
            app.recordwave = [0]; %default recorded file is a 0
        end

        % Button pushed function: C3
        function C3ButtonPushed(app, event)
            play_sound(app, 130.813, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Value changing function: DistortionSlider
        function DistortionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.distortion = changingValue;
        end

        % Value changed function: WaveTypeListBox
        function WaveTypeListBoxValueChanged(app, event)
            value = app.WaveTypeListBox.Value;
            if strcmp(value, 'Sine')
                app.sinwave = 1;
                app.sawwave = 0;
                app.squarewave = 0;
            elseif strcmp(value, 'Saw')
                app.sinwave = 0;
                app.sawwave = 1;
                app.squarewave = 0;
            elseif strcmp(value, 'Square')
                app.sinwave = 0;
                app.sawwave = 0;
                app.squarewave = 1;
            end
        end

        % Button pushed function: C3_Sharp
        function C3_SharpButtonPushed(app, event)
            play_sound(app, 138.591, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: D3
        function D3ButtonPushed(app, event)
            play_sound(app, 146.832, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: D3_Sharp
        function D3_SharpButtonPushed(app, event)
            play_sound(app, 155.563, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: E3Button
        function E3ButtonPushed(app, event)
            play_sound(app, 164.841, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: F3
        function F3ButtonPushed(app, event)
            play_sound(app, 174.614, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: F3_Sharp
        function F3_SharpButtonPushed(app, event)
            play_sound(app, 184.997, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: G3
        function G3ButtonPushed(app, event)
            play_sound(app, 195.998, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: G3_Sharp
        function G3_SharpButtonPushed(app, event)
            play_sound(app, 207.652, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: A3
        function A3ButtonPushed(app, event)
            play_sound(app, 220.000, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: A3_Sharp
        function A3_SharpButtonPushed(app, event)
            play_sound(app, 233.082, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: B3
        function B3ButtonPushed(app, event)
            play_sound(app, 246.942, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: C4
        function C4ButtonPushed(app, event)
            play_sound(app, 261.626, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: C4_Sharp
        function C4_SharpButtonPushed(app, event)
            play_sound(app, 277.183, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: D4
        function D4ButtonPushed(app, event)
            play_sound(app, 293.665, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: D4_Sharp
        function D4_SharpButtonPushed(app, event)
            play_sound(app, 311.127, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: E4
        function E4ButtonPushed(app, event)
            play_sound(app, 329.628, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: F4
        function F4ButtonPushed(app, event)
            play_sound(app, 349.228, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: F4_Sharp
        function F4_SharpButtonPushed(app, event)
            play_sound(app, 369.994, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: G4
        function G4ButtonPushed(app, event)
            play_sound(app, 391.995, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: G4_Sharp
        function G4_SharpButtonPushed(app, event)
            play_sound(app, 415.305, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: A4
        function A4ButtonPushed(app, event)
            play_sound(app, 440.000, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: A4_Sharp
        function A4_SharpButtonPushed(app, event)
            play_sound(app, 466.164, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Button pushed function: B4
        function B4ButtonPushed(app, event)
            play_sound(app, 493.883, app.distortion, app.sinwave, app.sawwave, app.squarewave)
            %plays sound at required frequency
        end

        % Value changed function: RecordSwitch
        function RecordSwitchValueChanged(app, event)
            value = app.RecordSwitch.Value;
            if strcmp(value, 'On')
               tic %starts or resets the timer for the recording
            end
        end

        % Value changed function: RecordingLengthInSecondsSlider
        function RecordingLengthInSecondsSliderValueChanged(app, event)
            value = app.RecordingLengthInSecondsSlider.Value; 
            value = round(value); %rounds the recording time to an integer for easier usage down the line
            app.recordwave = zeros(1, value*app.fs); %preallocating the matrix for recording
        end

        % Value changed function: PlayRecordingSwitch
        function PlayRecordingSwitchValueChanged(app, event)
            value = app.PlayRecordingSwitch.Value;
            if strcmp(value, 'On')
                sound(app.recordwave, app.fs) %if it is on, it plays the recording
            elseif strcmp(value, 'Off')
                clear sound %if turned off, all sound is turned off
            end
        end

        % Button pushed function: ExportRecordingButton
        function ExportRecordingButtonPushed(app, event)
            if isempty(app.recordwave) %if the wave is completely empty, outputs a recording with value 0.
                audiowrite('recording.wav',[0],app.fs);
            else
                audiowrite('recording.wav',app.recordwave,app.fs); %if it is not empty, it outputs the stored recording.
            end
            
        end

        % Button pushed function: ResetRecordingButton
        function ResetRecordingButtonPushed(app, event)
            %resets the recording for the same time interval
            value = app.RecordingLengthInSecondsSlider.Value; 
            value = round(value); %rounds the recording time to an integer for easier usage down the line
            app.recordwave = zeros(1, value*app.fs); %preallocating the matrix for recording
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.C3 = uibutton(app.UIFigure, 'push');
            app.C3.ButtonPushedFcn = createCallbackFcn(app, @C3ButtonPushed, true);
            app.C3.Text = 'C3';
            app.C3_Sharp = uibutton(app.UIFigure, 'push');
            app.C3_Sharp.ButtonPushedFcn = createCallbackFcn(app, @C3_SharpButtonPushed, true);
            app.C3_Sharp.Text = 'C3#';
            app.D3 = uibutton(app.UIFigure, 'push');
            app.D3.ButtonPushedFcn = createCallbackFcn(app, @D3ButtonPushed, true);
            app.D3.Text = 'D3';
            app.D3_Sharp = uibutton(app.UIFigure, 'push');
            app.D3_Sharp.ButtonPushedFcn = createCallbackFcn(app, @D3_SharpButtonPushed, true);
            app.D3_Sharp.Text = 'D3#';
            app.E3Button = uibutton(app.UIFigure, 'push');
            app.E3Button.ButtonPushedFcn = createCallbackFcn(app, @E3ButtonPushed, true);
            app.E3Button.Text = 'E3';
            app.F3 = uibutton(app.UIFigure, 'push');
            app.F3.ButtonPushedFcn = createCallbackFcn(app, @F3ButtonPushed, true);
            app.F3.Text = 'F3';
            app.F3_Sharp = uibutton(app.UIFigure, 'push');
            app.F3_Sharp.ButtonPushedFcn = createCallbackFcn(app, @F3_SharpButtonPushed, true);
            app.F3_Sharp.Text = 'F3#';
            app.G3 = uibutton(app.UIFigure, 'push');
            app.G3.ButtonPushedFcn = createCallbackFcn(app, @G3ButtonPushed, true);
            app.G3.Text = 'G3';
            app.G3_Sharp = uibutton(app.UIFigure, 'push');
            app.G3_Sharp.ButtonPushedFcn = createCallbackFcn(app, @G3_SharpButtonPushed, true);
            app.G3_Sharp.Text = 'G3#';
            app.A3 = uibutton(app.UIFigure, 'push');
            app.A3.ButtonPushedFcn = createCallbackFcn(app, @A3ButtonPushed, true);
            app.A3.Text = 'A3';
            app.A3_Sharp = uibutton(app.UIFigure, 'push');
            app.A3_Sharp.ButtonPushedFcn = createCallbackFcn(app, @A3_SharpButtonPushed, true);
            app.A3_Sharp.Text = 'A3#';
            app.B3 = uibutton(app.UIFigure, 'push');
            app.B3.ButtonPushedFcn = createCallbackFcn(app, @B3ButtonPushed, true);
            app.B3.Text = 'B3';
            app.WaveTypeListBoxLabel = uilabel(app.UIFigure);
            app.WaveTypeListBoxLabel.Text = 'Wave Type';
            app.WaveTypeListBox = uilistbox(app.UIFigure);
            app.WaveTypeListBox.Items = {'Sine', 'Saw', 'Square'};
            app.WaveTypeListBox.ValueChangedFcn = createCallbackFcn(app, @WaveTypeListBoxValueChanged, true);
            app.WaveTypeListBox.Value = 'Sine';
            app.RecordSwitchLabel = uilabel(app.UIFigure);
            app.RecordSwitchLabel.Text = 'Record';
            app.RecordSwitch = uiswitch(app.UIFigure, 'slider');
            app.RecordSwitch.ValueChangedFcn = createCallbackFcn(app, @RecordSwitchValueChanged, true);
            app.PlayRecordingSwitchLabel = uilabel(app.UIFigure);
            app.PlayRecordingSwitchLabel.Text = 'Play Recording';
            app.PlayRecordingSwitch = uiswitch(app.UIFigure, 'toggle');
            app.PlayRecordingSwitch.ValueChangedFcn = createCallbackFcn(app, @PlayRecordingSwitchValueChanged, true);
            app.WaveDisplay = uiaxes(app.UIFigure);
            title(app.WaveDisplay, 'Most Recent Sound Wave')
            xlabel(app.WaveDisplay, 'Indices for Wave Values')
            ylabel(app.WaveDisplay, 'Amplitude of Waves')
            app.C4 = uibutton(app.UIFigure, 'push');
            app.C4.ButtonPushedFcn = createCallbackFcn(app, @C4ButtonPushed, true);
            app.C4.Text = 'C4';
            app.C4_Sharp = uibutton(app.UIFigure, 'push');
            app.C4_Sharp.ButtonPushedFcn = createCallbackFcn(app, @C4_SharpButtonPushed, true);
            app.C4_Sharp.Text = 'C4#';
            app.D4 = uibutton(app.UIFigure, 'push');
            app.D4.ButtonPushedFcn = createCallbackFcn(app, @D4ButtonPushed, true);
            app.D4.Text = 'D4';
            app.D4_Sharp = uibutton(app.UIFigure, 'push');
            app.D4_Sharp.ButtonPushedFcn = createCallbackFcn(app, @D4_SharpButtonPushed, true);
            app.D4_Sharp.Text = 'D4#';
            app.E4 = uibutton(app.UIFigure, 'push');
            app.E4.ButtonPushedFcn = createCallbackFcn(app, @E4ButtonPushed, true);
            app.E4.Text = 'E4';
            app.F4 = uibutton(app.UIFigure, 'push');
            app.F4.ButtonPushedFcn = createCallbackFcn(app, @F4ButtonPushed, true);
            app.F4.Text = 'F4';
            app.F4_Sharp = uibutton(app.UIFigure, 'push');
            app.F4_Sharp.ButtonPushedFcn = createCallbackFcn(app, @F4_SharpButtonPushed, true);
            app.F4_Sharp.Text = 'F4#';
            app.G4 = uibutton(app.UIFigure, 'push');
            app.G4.ButtonPushedFcn = createCallbackFcn(app, @G4ButtonPushed, true);
            app.G4.Text = 'G4';
            app.G4_Sharp = uibutton(app.UIFigure, 'push');
            app.G4_Sharp.ButtonPushedFcn = createCallbackFcn(app, @G4_SharpButtonPushed, true);
            app.G4_Sharp.Text = 'G4#';
            app.A4 = uibutton(app.UIFigure, 'push');
            app.A4.ButtonPushedFcn = createCallbackFcn(app, @A4ButtonPushed, true);
            app.A4.Text = 'A4';
            app.A4_Sharp = uibutton(app.UIFigure, 'push');
            app.A4_Sharp.ButtonPushedFcn = createCallbackFcn(app, @A4_SharpButtonPushed, true);
            app.A4_Sharp.Text = 'A4#';
            app.B4 = uibutton(app.UIFigure, 'push');
            app.B4.ButtonPushedFcn = createCallbackFcn(app, @B4ButtonPushed, true);
            app.B4.Text = 'B4';
            app.DistortionSliderLabel = uilabel(app.UIFigure);
            app.DistortionSliderLabel.Text = 'Distortion';
            app.DistortionSlider = uislider(app.UIFigure);
            app.DistortionSlider.Limits = [0 1];
            app.DistortionSlider.ValueChangingFcn = createCallbackFcn(app, @DistortionSliderValueChanging, true);
            app.ExportRecordingButton = uibutton(app.UIFigure, 'push');
            app.ExportRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRecordingButtonPushed, true);
            app.ExportRecordingButton.Text = 'Export Recording';
            app.RecordingLengthInSecondsSliderLabel = uilabel(app.UIFigure);
            app.RecordingLengthInSecondsSliderLabel.Text = {'Recording Length'; 'In Seconds'};
            app.RecordingLengthInSecondsSlider = uislider(app.UIFigure);
            app.RecordingLengthInSecondsSlider.Limits = [0 60];
            app.RecordingLengthInSecondsSlider.ValueChangedFcn = createCallbackFcn(app, @RecordingLengthInSecondsSliderValueChanged, true);
            app.ResetRecordingButton = uibutton(app.UIFigure, 'push');
            app.ResetRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRecordingButtonPushed, true);
            app.ResetRecordingButton.Text = 'Reset Recording';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synthesizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end